classdef ui_slider2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GENERALHelp                  matlab.ui.control.Button
        ADVANCEDDropDown             matlab.ui.control.DropDown
        ADVANCEDDropDownLabel        matlab.ui.control.Label
        ADVANCEDGo                   matlab.ui.control.Button
        FINISH                       matlab.ui.control.Button
        ADDNEW                       matlab.ui.control.Button
        REVIEWNo                     matlab.ui.control.Button
        REVIEWLabel2                 matlab.ui.control.Label
        REVIEWApply                  matlab.ui.control.Button
        REVIEWLabel                  matlab.ui.control.Label
        SELECTLabel2                 matlab.ui.control.Label
        SELECTComplete               matlab.ui.control.Label
        SELECTApply                  matlab.ui.control.Button
        SELECTLabel                  matlab.ui.control.Label
        THRESHSlider                 matlab.ui.control.Slider
        ThresholdreativetoOtsuLabel  matlab.ui.control.Label
        THRESHNum                    matlab.ui.control.NumericEditField
        THRESHApply                  matlab.ui.control.Button
        THRESHComplete               matlab.ui.control.Label
        THRESHLabel                  matlab.ui.control.Label
        THRESHHelp                   matlab.ui.control.Button
        LASSOLabel2                  matlab.ui.control.Label
        LASSOComplete                matlab.ui.control.Label
        LASSOApply                   matlab.ui.control.Button
        LASSOLabel                   matlab.ui.control.Label
        CROPLabel2                   matlab.ui.control.Label
        CROPComplete                 matlab.ui.control.Label
        CROPApply                    matlab.ui.control.Button
        CROPLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        imgs0           % store original images
        
        idx             % index of current image
        img0            % store original image
        img_binary      % binary mask, main output from each iteration
        
        img_cropped     % image following CROP stage
        img_lasso       % binary image following LASSO stage
        img_cropped_binary  % binary version of the cropped image from CROP stage
        
        roi_cropped     % ROI after cropping the larger image
        roi_lasso       % ROI for binary image after LASSO stage
        roi_pts         % ROI for selecting aggregates in SELECT stage
        
        otsu_lvl        % threshold value for Otsu used in SLIDER stage
        
        f_morepts       % flag to keep selecting aggregates in SELECT stage
        
        crop_pos        % positions for cropped portion of image
    end
    
    properties (Access = public)
        imgs_binary     % store binary images, which act as output
        f_return = 0    % flag indicating if app is finished, used to resume program
    end
    
    methods (Access = private)
        
        function uiimshow(~, img, ax)
            
            imshow(img, 'parent', ax); % show image
            
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            ax.Title.String = '';
            
            set(ax, 'visible', 'on');
            set(ax,'XTick',[]); % remove x-ticks
            set(ax,'YTick',[]); % remove y-ticks
            ax.BoxStyle = 'full';
            
            ax.XLim = [0 size(img,2)-0.01];
            ax.YLim = [0 size(img,1)-0.01];
            
        end
        
        
        function img_refined = background_fnc(~, img_binary, img_cropped)
            
            nagg = nnz(img_binary); % pixels within the aggregate
            ntot = numel(img_cropped); % pixels within the whole cropped image 
            nbg = ntot-nagg; % pixels in the backgound of the aggregate
            
            
            %-- Computing average background intensity -------------------------------%
            burned_img = img_cropped;
            burned_img(img_binary) = 0;
            mean_bg =  mean(mean(burned_img))*ntot/nbg;
            
            
            %-- Replace aggregate pixels' with intensity from the background ---------%
            img_bg = img_cropped;
            img_bg(img_binary) = mean_bg;
            
            
            %-- Fit a curved surface into Filled_img data ----------------------------%
            [x_d,y_d] = meshgrid(1:size(img_bg,2),1:size(img_bg,1));
            xdata = {x_d,y_d};
            fun = @(c,xdata) c(1).*xdata{1}.^2+c(2).*xdata{2}.^2+c(3).*xdata{1}.*xdata{2}+...
                c(4).*xdata{1}+c(5).*xdata{2}+c(6);
            
            c_start = [0 0 0 0 0 mean_bg];
            options = optimset('MaxFunEvals', 1000, 'MaxIter', 1000, ...
                'Display', 'none');
            [c] = lsqcurvefit(fun,c_start,xdata,double(img_bg),[],[],options);
            
            
            %-- Build the fitted surface ---------------------------------------------%
            img_bg_fit = zeros(size(img_bg));
            for ii = 1:size(img_bg,1)
                for jj = 1:size(img_bg,2)
                    img_bg_fit(ii,jj) = ...
                        c(1)*ii^2+c(2)*jj^2+c(3)*ii*jj+c(4)*ii+c(5)*jj+c(6);
                end
            end
            
            %-- Refine Cropped_img, using fitted surface -----------------------------%
            img_refined = mean_bg+double(img_cropped)-img_bg_fit;
            img_refined = uint8(img_refined);

        end
        
        
        function [img_out, img_binary3] = uiimshow_thresh(~, img_cropped, img_lasso, level)
            %-- Average filter -------------------------------------------------------%
            hav = fspecial('average');
            img_mod = imfilter(img_cropped, hav);
            
            
            %-- Median ---------------------------------------------------------------%
            % Examines a neighborhood of WxW matrix, takes and makes the centre of that
            % matrix the median of the original neighborhood
            W = 5;
            for ii=1:6 % repeatedly apply median filter, which will result in artifacts on edges
                img_mod = medfilt2(img_mod, [W W], 'symmetric');
            end
            % NOTE: The loop is intended to imitate the increasing amounts of 
            % median filter that is applied each time the slider button is clicked
            % in the original code. This was a bug in the previous software. 
            
            
            %-- Binary image via threshold value -------------------------------------%
            img_binary1 = imbinarize(img_mod, level);
            
            
            % Binary image via dilation, which
            % reduces initial noise and fills initial gaps.
            img_binary2 = imdilate(~img_binary1, strel('square',1));
            
            
            %-- Refining binary image. Before refinig, thresholding causes some ------%
            %   Errors, initiating from edges, grows towards the aggregate. In
            %   this section, external boundary, or background region, is utilized to
            %   eliminate detection errors in the background region.
            img_binary3 = 0 .* img_binary2;
            img_binary3(img_lasso) = img_binary2(img_lasso);
            img_binary = logical(img_binary3);
            
            % Impose the binary on the cropped image for display to user.
            % This will adjust as the threshold is updated.
            img_out = double(labeloverlay(img_cropped, img_binary, ...
                'Transparency', 0.5,...
                'Colormap', [1,0,1]));
        end
        
        
        function [] = uiimshow_binary(app, ax, imgs, imgs_binary)
            
            f_outline = 1;
            
            t0 = labeloverlay(imgs, imgs_binary, ...
                'Transparency', 0.5,...
                'Colormap', [0,1,1]);
            
            if ~f_outline
                i1 = t0;
            else % if adding an outline
                img_edge = edge(imgs_binary,'sobel');
                se = strel('disk',1);
                img_dilated = imdilate(img_edge,se);
                    % use dilation to strengthen the aggregate's outline
                i1 = uint8(~img_dilated).*t0;
                    % adds borders to labeled regions
            end
            
            app.uiimshow(i1, ax); % show labelled image
            
        end
        
        function [] = initialize(app, img)
            
            ax = app.UIAxes;
            app.uiimshow_binary(ax, img, app.img_binary);
            
            % Allow for cancelling of process
            app.ADDNEW.Text = 'CANCEL';
            app.ADDNEW.BackgroundColor = [1.00,0.41,0.16];
            app.ADDNEW.Enable = 1;
            
            % Initialize cropping procedure. 
            app.CROPApply.Text = 'Skip';
            app.CROPApply.Enable = 1;
            
            app.roi_cropped = [];
            app.roi_cropped = drawrectangle(ax); % user crops image
            app.CROPApply.Text = 'Apply';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgs, imgs_binary)
            
            imgs = agg.parse_inputs(imgs, []);
            
            % Parse image binaries as input.
            if ~exist('imgs_binary', 'var'); imgs_binary = []; end
            if isempty(imgs_binary)
                imgs_binary = imgs;
                for ii=1:length(imgs_binary)
                    imgs_binary{ii} = false(size(imgs{ii}));  % match size with falses
                end
            else  % otherwise, throw through parser
                imgs_binary = agg.parse_inputs(imgs_binary, []);
            end
            
            app.imgs0 = imgs;
            
            app.idx = 1;
            app.img0 = app.imgs0{app.idx};
            app.img_binary = imgs_binary{app.idx};
            
            if app.idx == length(app.imgs0)
                app.FINISH.Text = 'FINISH';
            end
            
            app.initialize(app.img0);
            
        end

        % Button pushed function: GENERALHelp
        function GENERALHelpButtonPushed(app, event)
            
            msgbox(['If at any point there is an issue with the ', ...
                'thresholding adjustment, use the CANCEL button to ', ...
                'start the current adjustment over.']);
            
        end

        % Button pushed function: CROPApply
        function CROPApplyButtonPushed(app, event)
            
            if ~isempty(app.roi_cropped)
                app.CROPComplete.Text = 'Applied';
                
                pos = app.roi_cropped.Position;
                app.crop_pos = floor(pos);
                
            else  % skip and just use full image
                uiresume(app.UIFigure);
                app.CROPComplete.Text = 'Skipped';
                
                app.crop_pos = [0, 0, fliplr(size(app.img0))];
                
            end
            
            l = app.crop_pos(1);
            t = app.crop_pos(2);
            w = app.crop_pos(3);
            h = app.crop_pos(4);
            
            app.img_cropped = app.img0(max(t:(t+h),1), max(l:(l+w),1));
            app.img_cropped_binary = app.img_binary(max(t:(t+h),1), max(l:(l+w),1));
            
            app.uiimshow_binary(app.UIAxes, ...
                app.img_cropped, ...
                app.img_cropped_binary);
            
            app.CROPApply.Enable = 0;
            app.CROPLabel.Enable = 0;
            app.CROPLabel2.Enable = 0;
            app.CROPComplete.FontColor = [0.39,0.83,0.07];
            
            app.LASSOLabel.Enable = 1;
            app.LASSOLabel2.Enable = 1;
            
            app.LASSOApply.Text = 'Skip';
            app.LASSOApply.Enable = 1;
            
            app.roi_lasso = [];
            app.roi_lasso = drawfreehand(app.UIAxes, 'Tag', 'drawnLasso');
            
            app.LASSOApply.Text = 'Apply';
            
        end

        % Button pushed function: LASSOApply
        function LASSOApplyButtonPushed(app, event)
            
            app.LASSOApply.Enable = 0;
            app.LASSOLabel.Enable = 0;
            app.LASSOLabel2.Enable = 0;
            
            % Indicate that background is being processed.
            app.LASSOComplete.Text = 'Processing...';
            app.LASSOComplete.FontColor = [0.93,0.69,0.13];
            
            drawnow();
            
            if ~isempty(app.roi_lasso)
                app.img_lasso = app.roi_lasso.createMask(app.img_cropped);
                app.LASSOComplete.FontColor = [0,1,1];
                
            else
                uiresume(app.UIFigure);
                
                app.img_lasso = false(size(app.img_cropped));
                app.img_lasso( ...
                    25:(size(app.img_cropped,1)-25), ...
                    25:(size(app.img_cropped,2)-25)) = true;
            end
            
            img_refined = app.background_fnc(app.img_lasso, app.img_cropped);
            
            app.uiimshow(img_refined, app.UIAxes);
            
            app.otsu_lvl = graythresh(img_refined);
            
            app.THRESHSlider.Value = 1.2;
            multiplier = app.THRESHSlider.Value;
            app.THRESHNum.Value = round(multiplier * 100) / 100;
            lvl1 = app.otsu_lvl * multiplier;
            
            img_toshow = app.uiimshow_thresh(app.img_cropped, app.img_lasso, lvl1);
            app.uiimshow(img_toshow ./ max(max(img_toshow)), app.UIAxes);
            
            app.LASSOComplete.FontColor = [0.39,0.83,0.07];
            if ~isempty(app.roi_lasso)
                app.LASSOComplete.Text = 'Applied';
            else
                app.LASSOComplete.Text = 'Used border pixels';
            end
            
            app.THRESHApply.Enable = 1;
            app.THRESHLabel.Enable = 1;
            app.THRESHSlider.Enable = 1;
            app.THRESHHelp.Enable = 1;
            app.THRESHNum.Enable = 1;
            
        end

        % Value changed function: THRESHSlider
        function THRESHSliderValueChanged(app, event)
            
            multiplier = app.THRESHSlider.Value;
            app.THRESHNum.Value = round(multiplier * 100) / 100;
            lvl1 = app.otsu_lvl * multiplier;
            
            img_toshow = app.uiimshow_thresh(app.img_cropped, app.img_lasso, lvl1);
            app.uiimshow(img_toshow ./ max(max(img_toshow)), app.UIAxes);
            
        end

        % Value changed function: THRESHNum
        function THRESHNumValueChanged(app, event)
            
            multiplier = app.THRESHNum.Value;
            app.THRESHSlider.Value = multiplier;
            lvl1 = app.otsu_lvl * multiplier;
            
            img_toshow = app.uiimshow_thresh(app.img_cropped, app.img_lasso, lvl1);
            app.uiimshow(img_toshow ./ max(max(img_toshow)), app.UIAxes);
            
        end

        % Button pushed function: THRESHApply
        function THRESHApplyButtonPushed(app, event)
            
            multiplier = app.THRESHSlider.Value;
            lvl1 = app.otsu_lvl * multiplier;
            
            [~, app.img_cropped_binary] = app.uiimshow_thresh( ...
                app.img_cropped, app.img_lasso, lvl1);
            
            app.uiimshow(app.img_cropped_binary, app.UIAxes);
            
            app.THRESHApply.Enable = 0;
            app.THRESHLabel.Enable = 0;
            app.THRESHSlider.Enable = 0;
            app.THRESHHelp.Enable = 0;
            app.THRESHNum.Enable = 0;
            app.THRESHComplete.Text = 'Complete';
            app.THRESHComplete.FontColor = [0.39,0.83,0.07];
            
            app.SELECTLabel.Enable = 1;
            app.SELECTLabel2.Enable = 1;
            app.SELECTApply.Enable = 1;
            app.REVIEWNo.Enable = 1;
            app.SELECTApply.Text = 'Skip';
            
            app.f_morepts = 1;
            
            app.roi_pts = {};
            app.roi_pts = {drawpoint(app.UIAxes)};
            
            app.SELECTApply.Text = 'Apply';
            
            while app.f_morepts == 1
                app.roi_pts = [app.roi_pts, {drawpoint(app.UIAxes)}];
            end
            
        end

        % Button pushed function: THRESHHelp
        function THRESHHelpButtonPushed(app, event)
            
            msgbox(['If an overlay does not appear, thresholding may ', ...
                'be failing for this specific section of the image. ', ...
                'Consider canceling the current addition, adding a new ', ...
                'region to the binary mask, and applying a more precise ', ...
                'lasso to the region of interest.']);

        end

        % Button pushed function: SELECTApply
        function SELECTApplyButtonPushed(app, event)
            
            app.f_morepts = 0;
            uiresume(app.UIFigure);
            
            if ~isempty(app.roi_pts)
                cc = bwconncomp(app.img_cropped_binary, 4);
                f_accept = zeros(cc.NumObjects, 1);
                
                for ii=1:length(app.roi_pts)
                    pt = [round(app.roi_pts{ii}.Position(1)), round(app.roi_pts{ii}.Position(2))];
                    if pt(1) == 0  % if extra pt. returned, skip to next
                        continue;
                    end
                    
                    pt = sub2ind(size(app.img_cropped), pt(2), pt(1));
                    for jj=1:length(f_accept)
                        if ~isempty(find(pt == cc.PixelIdxList{jj}, 1))
                            f_accept(jj) = 1;
                        end
                    end
                end
                
                i1 = zeros(size(app.img_cropped)); i1 = i1(:);
                for jj=1:length(f_accept)
                    if f_accept(jj)
                        i1(cc.PixelIdxList{jj}) = 1;
                    end
                end
                i1 = reshape(i1, size(app.img_cropped));
                
                app.img_cropped_binary = i1;
                
                app.SELECTComplete.Text = 'Applied';
                
            else
                app.SELECTComplete.Text = 'Skipped';
                
            end
            
            
            app.uiimshow_binary(app.UIAxes, ...
                app.img_cropped, app.img_cropped_binary);
            
            app.SELECTApply.Enable = 0;
            app.SELECTLabel.Enable = 0;
            app.SELECTLabel2.Enable = 0;
            app.SELECTComplete.FontColor = [0.39,0.83,0.07];
            
            app.REVIEWLabel.Enable = 1;
            app.REVIEWLabel2.Enable = 1;
            app.REVIEWApply.Enable = 1;
            
            app.REVIEWNo.Enable = 1;
            
        end

        % Button pushed function: REVIEWApply
        function REVIEWApplyButtonPushed(app, event)
            
            % Add into overall binary mask.
            l = app.crop_pos(1);
            t = app.crop_pos(2);
            w = app.crop_pos(3);
            h = app.crop_pos(4);
            
            ir = {max(t:(t+h),1), max(l:(l+w),1)};
            
            app.img_binary(ir{1}, ir{2}) = or( ...
                app.img_binary(ir{1}, ir{2}), ...
                app.img_cropped_binary);
            
            app.uiimshow_binary(app.UIAxes, ...
                app.img0, app.img_binary);
            
            
            app.REVIEWApply.Enable = 0;
            app.REVIEWLabel.Enable = 0;
            app.REVIEWLabel2.Enable = 0;
            app.REVIEWNo.Enable = 0;
            
            app.ADDNEW.Enable = 1;
            app.ADDNEW.BackgroundColor = [0.39,0.83,0.07];
            app.ADDNEW.Text = 'ADD TO BINARY MASK';
            
            app.FINISH.Enable = 1;
            
        end

        % Button pushed function: REVIEWNo
        function REVIEWNoButtonPushed(app, event)
            
            app.REVIEWApply.Enable = 0;
            app.REVIEWLabel.Enable = 0;
            app.REVIEWLabel2.Enable = 0;
            app.REVIEWNo.Enable = 0;
            
            app.ADDNEWButtonPushed(event);
            
        end

        % Button pushed function: ADDNEW
        function ADDNEWButtonPushed(app, event)
            
            % Code for cancelling a process.
            if strcmp(app.ADDNEW.Text, 'CANCEL')
                
                % In case a selection was in process.
                uiresume(app.UIFigure);
                
                % Disable all buttons.
                app.CROPLabel.Enable = 0;
                app.LASSOLabel.Enable = 0;
                app.THRESHLabel.Enable = 0;
                app.SELECTLabel.Enable = 0;
                
                app.CROPLabel2.Enable = 0;
                app.LASSOLabel2.Enable = 0;
                app.SELECTLabel2.Enable = 0;
                
                app.CROPApply.Enable = 0;
                app.LASSOApply.Enable = 0;
                app.THRESHApply.Enable = 0;
                app.SELECTApply.Enable = 0;
                
                app.THRESHSlider.Enable = 0;
                
                % Allow user to continue or add new aggregates.
                app.ADDNEW.Enable = 1;
                app.ADDNEW.BackgroundColor = [0.39,0.83,0.07];
                app.ADDNEW.Text = 'ADD TO BINARY MASK';
                
                app.FINISH.Enable = 1;
                
                app.uiimshow_binary(app.UIAxes, ...
                    app.img0, app.img_binary);
                
                return;
            end
            
            % Else, clicked in order to add aggregates.
            app.ADDNEW.Enable = 0;
            app.FINISH.Enable = 0;
            
            app.CROPComplete.Text = 'Incomplete';
            app.CROPComplete.FontColor = [1.00,0.41,0.16];
            
            app.LASSOComplete.Text = 'Incomplete';
            app.LASSOComplete.FontColor = [1.00,0.41,0.16];
            
            app.THRESHComplete.Text = 'Incomplete';
            app.THRESHComplete.FontColor = [1.00,0.41,0.16];
            
            app.SELECTComplete.Text = 'Incomplete';
            app.SELECTComplete.FontColor = [1.00,0.41,0.16];

            app.THRESHNum.Value = 0;
            
            app.CROPApply.Enable = 1;
            app.CROPLabel.Enable = 1;
            app.CROPLabel2.Enable = 1;
            
            app.initialize(app.img0);
            
        end

        % Button pushed function: FINISH
        function FINISHButtonPushed(app, event)
            
            app.imgs_binary{app.idx} = app.img_binary;
            
            if app.idx == length(app.imgs0)  % finish with app
                commandwindow;
                app.f_return = 1;
            
            elseif app.idx == (length(app.imgs0) - 1)
                app.FINISH.Text = 'FINISH';
                
                app.ADDNEW.Enable = 0;
            end
            
            if app.idx < length(app.imgs0)
                % go to next image
                app.idx = app.idx + 1;
                app.img0 = app.imgs0{app.idx};
                app.img_binary = false(size(app.img0));
                
                app.ADDNEW.Enable = 0;
                app.FINISH.Enable = 0;
                
                app.CROPComplete.Text = 'Incomplete';
                app.CROPComplete.FontColor = [1.00,0.41,0.16];
                
                app.LASSOComplete.Text = 'Incomplete';
                app.LASSOComplete.FontColor = [1.00,0.41,0.16];
                
                app.THRESHComplete.Text = 'Incomplete';
                app.THRESHComplete.FontColor = [1.00,0.41,0.16];
                
                app.SELECTComplete.Text = 'Incomplete';
                app.SELECTComplete.FontColor = [1.00,0.41,0.16];
                
                app.CROPApply.Enable = 1;
                app.CROPLabel.Enable = 1;
                app.CROPLabel2.Enable = 1;
                
                app.initialize(app.img0);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            uiresume(app.UIFigure);  % resume in case selection currently active
            commandwindow;  % go to command window (where info extracted and app deleted)
            app.f_return = 1;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {729, 729};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CROPLabel = uilabel(app.LeftPanel);
            app.CROPLabel.Text = 'CROP';
            app.CROPApply = uibutton(app.LeftPanel, 'push');
            app.CROPApply.ButtonPushedFcn = createCallbackFcn(app, @CROPApplyButtonPushed, true);
            app.CROPApply.Text = 'Apply';
            app.CROPComplete = uilabel(app.LeftPanel);
            app.CROPComplete.Text = 'Incomplete';
            app.CROPLabel2 = uilabel(app.LeftPanel);
            app.CROPLabel2.Text = 'Draw a box on the image.';
            app.LASSOLabel = uilabel(app.LeftPanel);
            app.LASSOLabel.Enable = 'off';
            app.LASSOLabel.Text = 'LASSO';
            app.LASSOApply = uibutton(app.LeftPanel, 'push');
            app.LASSOApply.ButtonPushedFcn = createCallbackFcn(app, @LASSOApplyButtonPushed, true);
            app.LASSOApply.Enable = 'off';
            app.LASSOApply.Text = 'Apply';
            app.LASSOComplete = uilabel(app.LeftPanel);
            app.LASSOComplete.Text = 'Incomplete';
            app.LASSOLabel2 = uilabel(app.LeftPanel);
            app.LASSOLabel2.Enable = 'off';
            app.LASSOLabel2.Text = 'Use lasso tool to circle aggr.';
            app.THRESHHelp = uibutton(app.LeftPanel, 'push');
            app.THRESHHelp.ButtonPushedFcn = createCallbackFcn(app, @THRESHHelpButtonPushed, true);
            app.THRESHHelp.Enable = 'off';
            app.THRESHHelp.Text = '?';
            app.THRESHLabel = uilabel(app.LeftPanel);
            app.THRESHLabel.Enable = 'off';
            app.THRESHLabel.Text = 'SLIDER';
            app.THRESHComplete = uilabel(app.LeftPanel);
            app.THRESHComplete.Text = 'Incomplete';
            app.THRESHApply = uibutton(app.LeftPanel, 'push');
            app.THRESHApply.ButtonPushedFcn = createCallbackFcn(app, @THRESHApplyButtonPushed, true);
            app.THRESHApply.Enable = 'off';
            app.THRESHApply.Text = 'Apply';
            app.THRESHNum = uieditfield(app.LeftPanel, 'numeric');
            app.THRESHNum.ValueChangedFcn = createCallbackFcn(app, @THRESHNumValueChanged, true);
            app.THRESHNum.Enable = 'off';
            app.ThresholdreativetoOtsuLabel = uilabel(app.LeftPanel);
            app.ThresholdreativetoOtsuLabel.Enable = 'off';
            app.ThresholdreativetoOtsuLabel.Text = 'Threshold (reative to Otsu).';
            app.THRESHSlider = uislider(app.LeftPanel);
            app.THRESHSlider.Limits = [0.5 3];
            app.THRESHSlider.ValueChangedFcn = createCallbackFcn(app, @THRESHSliderValueChanged, true);
            app.THRESHSlider.MinorTicks = [];
            app.THRESHSlider.Enable = 'off';
            app.THRESHSlider.Value = 0.5;
            app.SELECTLabel = uilabel(app.LeftPanel);
            app.SELECTLabel.Enable = 'off';
            app.SELECTLabel.Text = 'SELECT';
            app.SELECTApply = uibutton(app.LeftPanel, 'push');
            app.SELECTApply.ButtonPushedFcn = createCallbackFcn(app, @SELECTApplyButtonPushed, true);
            app.SELECTApply.Enable = 'off';
            app.SELECTApply.Text = 'Apply';
            app.SELECTComplete = uilabel(app.LeftPanel);
            app.SELECTComplete.Text = 'Incomplete';
            app.SELECTLabel2 = uilabel(app.LeftPanel);
            app.SELECTLabel2.Enable = 'off';
            app.SELECTLabel2.Text = {'Select the relevant binary '; 'part of the image.'};
            app.REVIEWLabel = uilabel(app.LeftPanel);
            app.REVIEWLabel.Enable = 'off';
            app.REVIEWLabel.Text = 'REVIEW';
            app.REVIEWApply = uibutton(app.LeftPanel, 'push');
            app.REVIEWApply.ButtonPushedFcn = createCallbackFcn(app, @REVIEWApplyButtonPushed, true);
            app.REVIEWApply.Enable = 'off';
            app.REVIEWApply.Text = 'Yes';
            app.REVIEWLabel2 = uilabel(app.LeftPanel);
            app.REVIEWLabel2.Enable = 'off';
            app.REVIEWLabel2.Text = 'Add to overall binary mask?';
            app.REVIEWNo = uibutton(app.LeftPanel, 'push');
            app.REVIEWNo.ButtonPushedFcn = createCallbackFcn(app, @REVIEWNoButtonPushed, true);
            app.REVIEWNo.Enable = 'off';
            app.REVIEWNo.Text = 'No';
            app.ADDNEW = uibutton(app.LeftPanel, 'push');
            app.ADDNEW.ButtonPushedFcn = createCallbackFcn(app, @ADDNEWButtonPushed, true);
            app.ADDNEW.Enable = 'off';
            app.ADDNEW.Text = 'ADD TO BINARY MASK';
            app.FINISH = uibutton(app.LeftPanel, 'push');
            app.FINISH.ButtonPushedFcn = createCallbackFcn(app, @FINISHButtonPushed, true);
            app.FINISH.Enable = 'off';
            app.FINISH.Text = 'NEXT IMAGE';
            app.ADVANCEDGo = uibutton(app.LeftPanel, 'push');
            app.ADVANCEDGo.Enable = 'off';
            app.ADVANCEDGo.Text = 'GO';
            app.ADVANCEDDropDownLabel = uilabel(app.LeftPanel);
            app.ADVANCEDDropDownLabel.Enable = 'off';
            app.ADVANCEDDropDownLabel.Text = 'ADVANCED';
            app.ADVANCEDDropDown = uidropdown(app.LeftPanel);
            app.ADVANCEDDropDown.Items = {'Apply k-means', 'Remove aggregates'};
            app.ADVANCEDDropDown.Enable = 'off';
            app.ADVANCEDDropDown.Value = 'Apply k-means';
            app.GENERALHelp = uibutton(app.LeftPanel, 'push');
            app.GENERALHelp.ButtonPushedFcn = createCallbackFcn(app, @GENERALHelpButtonPushed, true);
            app.GENERALHelp.Text = '?';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_slider2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end